classdef start < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReconnaissancebiomtriqueparempreintepalmaireUIFigure  matlab.ui.Figure
        TrainButton               matlab.ui.control.Button
        GenuineButton             matlab.ui.control.Button
        ImposterButton            matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        EERButton                 matlab.ui.control.Button
        TempsdexcusionButton      matlab.ui.control.Button
        BestBlockButton           matlab.ui.control.Button
        AllButton                 matlab.ui.control.Button
        mergeButton               matlab.ui.control.Button
        calculatethefusionButton  matlab.ui.control.Button
        calculateentropyButton    matlab.ui.control.Button
        showentropyButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            run("mainTrain.m");
        end

        % Button pushed function: ImposterButton
        function ImposterButtonPushed(app, event)
            run("mainImposter.m");
        end

        % Button pushed function: GenuineButton
        function GenuineButtonPushed(app, event)
            run("mainGenuine.m");
        end

        % Button pushed function: AllButton
        function AllButtonPushed(app, event)
            run("main.m");
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             run("show_plot.m");
        end

        % Button pushed function: EERButton
        function EERButtonPushed(app, event)
            run("show_EER.m");
        end

        % Button pushed function: TempsdexcusionButton
        function TempsdexcusionButtonPushed(app, event)
            run("show_TempExcution.m");
        end

        % Button pushed function: BestBlockButton
        function BestBlockButtonPushed(app, event)
            run("show_BestBloc.m");
        end

        % Button pushed function: mergeButton
        function mergeButtonPushed(app, event)
            run("show_fusion.m");
        end

        % Button pushed function: calculatethefusionButton
        function calculatethefusionButtonPushed(app, event)
             run("fusion.m");
        end

        % Button pushed function: calculateentropyButton
        function calculateentropyButtonPushed(app, event)
            run("entrepie.m");
        end

        % Button pushed function: showentropyButton
        function showentropyButtonPushed(app, event)
             run("show_entrepie.m");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure = uifigure('Visible', 'off');
            app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure.Name = 'Reconnaissance biométrique par empreinte palmaire';
            app.TrainButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.GenuineButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.GenuineButton.ButtonPushedFcn = createCallbackFcn(app, @GenuineButtonPushed, true);
            app.GenuineButton.Text = 'Genuine';
            app.ImposterButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.ImposterButton.ButtonPushedFcn = createCallbackFcn(app, @ImposterButtonPushed, true);
            app.ImposterButton.Text = 'Imposter';
            app.PlotButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EERButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.EERButton.ButtonPushedFcn = createCallbackFcn(app, @EERButtonPushed, true);
            app.EERButton.Text = 'EER';
            app.TempsdexcusionButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.TempsdexcusionButton.ButtonPushedFcn = createCallbackFcn(app, @TempsdexcusionButtonPushed, true);
            app.TempsdexcusionButton.Text = 'Temps d''excusion';
            app.BestBlockButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.BestBlockButton.ButtonPushedFcn = createCallbackFcn(app, @BestBlockButtonPushed, true);
            app.BestBlockButton.Text = 'Best Block';
            app.AllButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.AllButton.ButtonPushedFcn = createCallbackFcn(app, @AllButtonPushed, true);
            app.AllButton.Text = 'All';
            app.mergeButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.mergeButton.ButtonPushedFcn = createCallbackFcn(app, @mergeButtonPushed, true);
            app.mergeButton.Text = 'merge';
            app.calculatethefusionButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.calculatethefusionButton.ButtonPushedFcn = createCallbackFcn(app, @calculatethefusionButtonPushed, true);
            app.calculatethefusionButton.Text = 'calculate the fusion';
            app.calculateentropyButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.calculateentropyButton.ButtonPushedFcn = createCallbackFcn(app, @calculateentropyButtonPushed, true);
            app.calculateentropyButton.Text = 'calculate entropy';
            app.showentropyButton = uibutton(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure, 'push');
            app.showentropyButton.ButtonPushedFcn = createCallbackFcn(app, @showentropyButtonPushed, true);
            app.showentropyButton.Text = 'show entropy';
            % Show the figure after all components are created
            app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReconnaissancebiomtriqueparempreintepalmaireUIFigure)
        end
    end
end